classdef EpochVisualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        DataListBoxLabel         matlab.ui.control.Label
        DataListBox              matlab.ui.control.ListBox
        EpochVisualizationPanel  matlab.ui.container.Panel
        PreviousButton           matlab.ui.control.Button
        EditField_2              matlab.ui.control.NumericEditField
        NextButton               matlab.ui.control.Button
        ScaleEditFieldLabel      matlab.ui.control.Label
        ScaleEditField           matlab.ui.control.NumericEditField
        ManualRejectionPanel     matlab.ui.container.Panel
        GoodButton               matlab.ui.control.Button
        RejectButton             matlab.ui.control.Button
        EditField                matlab.ui.control.NumericEditField
        MLabel                   matlab.ui.control.Label
        AutomaticRejectionPanel  matlab.ui.container.Panel
        EditField_3              matlab.ui.control.NumericEditField
        AutoThreshLabel          matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
        ALabel                   matlab.ui.control.Label
        CompareMAButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        preprocessed; % Description
        prev_subj;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            current_path = pwd;
            dir_list = dir([current_path '\Preprocessed\*.mat']);
            n_subj = length(dir_list);
            
            for i = 1:n_subj
                app.DataListBox.Items{i} = dir_list(i).name(1:end-4);
            end
            app.DataListBox.Value = app.DataListBox.Items{1};
            
            value = app.DataListBox.Value;
            app.prev_subj = value;
            load([pwd '\Preprocessed\' value '.mat'])
            app.preprocessed = preprocessed;
            if isfield(app.preprocessed,'reject') == 0
                app.preprocessed.reject = zeros(1,150);
            end
            if isfield(app.preprocessed,'autoreject') == 0
                app.preprocessed.thr_auto = 5;
                app.EditField_3.Value = 5;
                app.preprocessed.rejected_datapoints = app.preprocessed.Zsum_t > app.EditField_3.Value;
                app.preprocessed.autoreject = sum(app.preprocessed.rejected_datapoints,1) > 0;
            else
                app.EditField_3.Value = app.preprocessed.thr_auto;
            end
            
            app.ScaleEditField.Value = 20;
            
            trial = 1;
            F_Visualize_Epoch_App(app,1000,trial)
            
            
        end

        % Value changed function: DataListBox
        function call_SubjSelected(app, event)
            selection = uiconfirm(app.UIFigure,'Save last subject data?','Closing app','Options',{'Yes','No'});
            if strcmpi(selection,'Yes')
                preprocessed = app.preprocessed;
                save([pwd '\Preprocessed\' app.prev_subj '.mat'],'preprocessed')
                clearvars preprocessed
            end
            
            value = app.DataListBox.Value;
            app.prev_subj = value;
            load([pwd '\Preprocessed\' value '.mat'])
            app.preprocessed = preprocessed;
            if isfield(app.preprocessed,'reject') == 0
                app.preprocessed.reject = zeros(1,150);
            end
            if isfield(app.preprocessed,'autoreject') == 0
                app.preprocessed.thr_auto = 5;
                app.EditField_3.Value = 5;
                app.preprocessed.rejected_datapoints = app.preprocessed.Zsum_t > app.EditField_3.Value;
                app.preprocessed.autoreject = sum(app.preprocessed.rejected_datapoints,1) > 0;
            else
                app.EditField_3.Value = app.preprocessed.thr_auto;
            end
            
            trial = 1;
            F_Visualize_Epoch_App(app,1000,trial)
        end

        % Button pushed function: PreviousButton
        function PreviousTrial(app, event)
            trial = app.EditField_2.Value;
            if trial > 1
                trial = trial-1;
                F_Visualize_Epoch_App(app,1000,trial)
            end
        end

        % Button pushed function: NextButton
        function NextTrial(app, event)
            trial = app.EditField_2.Value;
            if trial < 150
                trial = trial+1;
                F_Visualize_Epoch_App(app,1000,trial)
            end
        end

        % Value changed function: EditField_2
        function SelectTrial(app, event)
            trial = app.EditField_2.Value;
            if trial > 150
                trial = 150;
            elseif trial < 0
                trial = 0;
            end
            F_Visualize_Epoch_App(app,1000,trial)
            
        end

        % Button pushed function: GoodButton
        function TrialPass(app, event)
            trial = app.EditField_2.Value;
            app.preprocessed.reject(trial) = 0;
            F_Visualize_Epoch_App(app,1000,trial)
        end

        % Button pushed function: RejectButton
        function TrailReject(app, event)
            trial = app.EditField_2.Value;
            app.preprocessed.reject(trial) = 1;
            F_Visualize_Epoch_App(app,1000,trial)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Save last subject data?','Closing app','Options',{'Yes','No'});
            
            if strcmpi(selection,'Yes')
                preprocessed = app.preprocessed;
                save([pwd '\Preprocessed\' app.DataListBox.Value '.mat'],'preprocessed')
            end
            delete(app)
        end

        % Value changed function: EditField_3
        function ThresholdChange(app, event)
            value = app.EditField_3.Value;
            app.preprocessed.thr_auto = value;
            app.preprocessed.rejected_datapoints = app.preprocessed.Zsum_t > value;
            app.preprocessed.autoreject = sum(app.preprocessed.rejected_datapoints,1) > 0;
            
            F_Visualize_Epoch_App(app,1000,app.EditField_2.Value)
        end

        % Button pushed function: CompareMAButton
        function CompareMAButtonPushed(app, event)
            figure;
            plotconfusion(logical(app.preprocessed.reject),app.preprocessed.autoreject)
        end

        % Value changed function: ScaleEditField
        function ScaleValueChanged(app, event)
            F_Visualize_Epoch_App(app,1000,app.EditField_2.Value)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DataListBoxLabel = uilabel(app.UIFigure);
            app.DataListBoxLabel.Text = 'Data';
            app.DataListBox = uilistbox(app.UIFigure);
            app.DataListBox.Items = {};
            app.DataListBox.ValueChangedFcn = createCallbackFcn(app, @call_SubjSelected, true);
            app.DataListBox.Value = {};
            app.EpochVisualizationPanel = uipanel(app.UIFigure);
            app.EpochVisualizationPanel.Title = 'Epoch Visualization';
            app.PreviousButton = uibutton(app.EpochVisualizationPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousTrial, true);
            app.PreviousButton.Text = 'Previous';
            app.EditField_2 = uieditfield(app.EpochVisualizationPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @SelectTrial, true);
            app.NextButton = uibutton(app.EpochVisualizationPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextTrial, true);
            app.NextButton.Text = 'Next';
            app.ScaleEditFieldLabel = uilabel(app.EpochVisualizationPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.EpochVisualizationPanel, 'numeric');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleValueChanged, true);
            app.ManualRejectionPanel = uipanel(app.UIFigure);
            app.ManualRejectionPanel.Title = 'Manual Rejection';
            app.GoodButton = uibutton(app.ManualRejectionPanel, 'push');
            app.GoodButton.ButtonPushedFcn = createCallbackFcn(app, @TrialPass, true);
            app.GoodButton.Text = 'Good';
            app.RejectButton = uibutton(app.ManualRejectionPanel, 'push');
            app.RejectButton.ButtonPushedFcn = createCallbackFcn(app, @TrailReject, true);
            app.RejectButton.Text = 'Reject';
            app.EditField = uieditfield(app.ManualRejectionPanel, 'numeric');
            app.EditField.Editable = 'off';
            app.MLabel = uilabel(app.ManualRejectionPanel);
            app.MLabel.Text = 'M';
            app.AutomaticRejectionPanel = uipanel(app.UIFigure);
            app.AutomaticRejectionPanel.Title = 'Automatic Rejection';
            app.EditField_3 = uieditfield(app.AutomaticRejectionPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @ThresholdChange, true);
            app.EditField_3.Tooltip = {''};
            app.AutoThreshLabel = uilabel(app.AutomaticRejectionPanel);
            app.AutoThreshLabel.Text = 'Auto Thresh';
            app.Lamp = uilamp(app.AutomaticRejectionPanel);
            app.ALabel = uilabel(app.AutomaticRejectionPanel);
            app.ALabel.Text = 'A';
            app.CompareMAButton = uibutton(app.UIFigure, 'push');
            app.CompareMAButton.ButtonPushedFcn = createCallbackFcn(app, @CompareMAButtonPushed, true);
            app.CompareMAButton.Text = 'Compare M/A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EpochVisualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end